classdef Modulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModulationPropertiesLabel       matlab.ui.control.Label
        CarrierPropertiesLabel_2        matlab.ui.control.Label
        CarrierPropertiesLabel          matlab.ui.control.Label
        AMMuEditField                   matlab.ui.control.NumericEditField
        AMMuEditFieldLabel              matlab.ui.control.Label
        PMKpEditField                   matlab.ui.control.NumericEditField
        PMKpEditFieldLabel              matlab.ui.control.Label
        FMKfEditField                   matlab.ui.control.NumericEditField
        FMKfEditFieldLabel              matlab.ui.control.Label
        SNRPlotModulationDropDown       matlab.ui.control.DropDown
        SNRPlotModulationDropDownLabel  matlab.ui.control.Label
        UpdatePlotButton                matlab.ui.control.Button
        MessageAmplitudeEditField       matlab.ui.control.NumericEditField
        MessageAmplitudeEditFieldLabel  matlab.ui.control.Label
        CarrierAmplitudeEditField       matlab.ui.control.NumericEditField
        CarrierAmplitudeEditFieldLabel  matlab.ui.control.Label
        ModulationGUILabel              matlab.ui.control.Label
        MessageFreqEditField            matlab.ui.control.NumericEditField
        MessageFreqEditFieldLabel       matlab.ui.control.Label
        CarrierFreqEditField            matlab.ui.control.NumericEditField
        CarrierFreqEditFieldLabel       matlab.ui.control.Label
        SamplingFreqEditField           matlab.ui.control.NumericEditField
        SamplingFreqEditFieldLabel      matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    methods (Access = private)
        function UpdatePlot(app, selectedModulation)
            % Retrieve parameter values from UI fields
            Fs = app.SamplingFreqEditField.Value; % Sampling Freq
            dt = 1 / Fs; % Sampling Time
            Fc = app.CarrierFreqEditField.Value; % Carrier Freq
            Fm = app.MessageFreqEditField.Value; % Message Freq
            Ac = app.CarrierAmplitudeEditField.Value; % Carrier Amplitude
            Am = app.MessageAmplitudeEditField.Value; % Message Amplitude
            Kf = app.FMKfEditField.Value; % Frequency deviation factor for FM
            Kp = app.PMKpEditField.Value; % Phase deviation factor for PM
            Mu = app.AMMuEditField.Value; % Modulation index for AM

            % Time vector (1 second duration)
            T = 0:dt:1;

            % Message signal (modulating signal)
            MessageSig = Am * sin(2 * pi * Fm * T);

            % Carrier signal
            CarrSig = Ac * sin(2 * pi * Fc * T);

            % DSB Modulation
            DsbSig = MessageSig .* CarrSig;

            % VSB Modulation
            % Hilbert transform of the message signal
            MessageSigHilbert = hilbert(MessageSig);
            VsbSig = real(MessageSigHilbert .* (Ac * exp(1i * 2 * pi * Fc * T)));

            % SSB Modulation
            HilbertMsgSig = imag(hilbert(MessageSig));
            SsbSig = MessageSig .* cos(2 * pi * Fc * T) - HilbertMsgSig .* sin(2 * pi * Fc * T);

            % LSSB Modulation
            LssbSig = SsbSig .* (2 * pi * Fc * T >= pi/2);

            % USSB Modulation
            UssbSig = SsbSig .* (2 * pi * Fc * T < pi/2);

            % AM Modulation
            AMModSig = (1 + Mu * MessageSig) .* CarrSig;

            % FM Modulation
            FMModSig = sin(2 * pi * Fc * T + 2 * pi * Kf * cumsum(MessageSig) / Fs);

            % PM Modulation
            PMModSig = sin(2 * pi * Fc * T + Kp * MessageSig);

            % Select the modulation signal based on the dropdown menu
            switch selectedModulation
                case 'DSB'
                    ModSig = DsbSig;
                case 'VSB'
                    ModSig = VsbSig;
                case 'SSB'
                    ModSig = SsbSig;
                case 'AM'
                    ModSig = AMModSig;
                case 'FM'
                    ModSig = FMModSig;
                case 'PM'
                    ModSig = PMModSig;
                otherwise
                    ModSig = DsbSig;
            end

            % SNR Calculation
            GammaVals = 0:0.1:10;                % Different levels of gamma (ratio of signal amplitude to noise amplitude)
            SNRVals = zeros(size(GammaVals));    % Initialize SNR values

            for i = 1:length(GammaVals)
                gamma = GammaVals(i);
                Noise = gamma * randn(size(ModSig)); % Generate Gaussian noise
                NoisySig = ModSig + Noise;           % Add noise to modulated signal

                % Calculate signal power and noise power
                SigPow = mean(ModSig .^ 2);
                NoisePow = mean(Noise .^ 2);

                % Calculate SNR
                SNRVals(i) = 10 * log10(SigPow / NoisePow);
            end

            % Plotting
            Colors = [
                0.0, 0.4470, 0.7410;  % Blue
                0.8500, 0.3250, 0.0980;  % Red
                0.9290, 0.6940, 0.1250;  % Yellow
                0.4940, 0.1840, 0.5560;  % Purple
                0.4660, 0.6740, 0.1880;  % Green
                0.3010, 0.7450, 0.9330;  % Light Blue
                0.6350, 0.0780, 0.1840;  % Dark Red
                0.7500, 0.7500, 0.7500;  % Gray
                0.25, 0.25, 0.25;  % Dark Gray
                0.1, 0.6, 0.3;  % Teal Green
                0.7, 0.4, 0.1;  % Orange
                ];

            plot(app.UIAxes,   T, MessageSig, 'LineWidth', 2, 'Color', Colors(1, :));
            plot(app.UIAxes_2, T, CarrSig, 'LineWidth', 2, 'Color', Colors(2, :));
            plot(app.UIAxes_3, T, DsbSig, 'LineWidth', 2, 'Color', Colors(3, :));
            plot(app.UIAxes_4, T, VsbSig, 'LineWidth', 2, 'Color', Colors(4, :));
            plot(app.UIAxes_5, T, SsbSig, 'LineWidth', 2, 'Color', Colors(5, :));
            plot(app.UIAxes_6, T, LssbSig, 'LineWidth', 2, 'Color', Colors(6, :), 'DisplayName', 'LSSB'); hold(app.UIAxes_6, 'on');
            plot(app.UIAxes_6, T, UssbSig, 'LineWidth', 2, 'Color', Colors(7, :), 'DisplayName', 'USSB'); hold(app.UIAxes_6, 'off'); legend(app.UIAxes_6);
            plot(app.UIAxes_7, T, AMModSig, 'LineWidth', 2, 'Color', Colors(8, :));
            plot(app.UIAxes_8, T, PMModSig, 'LineWidth', 2, 'Color', Colors(9, :));
            plot(app.UIAxes_9, T, FMModSig, 'LineWidth', 2, 'Color', Colors(10, :));
            plot(app.UIAxes2, GammaVals, SNRVals, 'LineWidth', 2, 'Color', Colors(11, :));

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            selectedModulation = app.SNRPlotModulationDropDown.Value;
            UpdatePlot(app, selectedModulation);
        end

        % Value changed function: SNRPlotModulationDropDown
        function SNRPlotModulationDropDownValueChanged(app, event)
            selectedModulation = app.SNRPlotModulationDropDown.Value;
            UpdatePlot(app, selectedModulation);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Message Signal')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Carrier Signal')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'DSB Modulation')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'VSB Modulation')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'SSB Modulation')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'LSSB/USSB Modulation')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'AM Modulation')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'PM Modulation')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'FM Modulation')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'SNR Vs GAMMA')
            xlabel(app.UIAxes2, 'Gamma')
            ylabel(app.UIAxes2, 'SNR')
            zlabel(app.UIAxes2, 'Z')
            app.SamplingFreqEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFreqEditFieldLabel.Text = 'Sampling Freq';
            app.SamplingFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFreqEditField.Limits = [0 100000];
            app.SamplingFreqEditField.RoundFractionalValues = 'on';
            app.SamplingFreqEditField.Value = 250;
            app.CarrierFreqEditFieldLabel = uilabel(app.UIFigure);
            app.CarrierFreqEditFieldLabel.Text = 'Carrier Freq';
            app.CarrierFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarrierFreqEditField.Limits = [0 Inf];
            app.CarrierFreqEditField.Value = 10;
            app.MessageFreqEditFieldLabel = uilabel(app.UIFigure);
            app.MessageFreqEditFieldLabel.Text = 'Message Freq';
            app.MessageFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.MessageFreqEditField.Limits = [0 Inf];
            app.MessageFreqEditField.Value = 5;
            app.ModulationGUILabel = uilabel(app.UIFigure);
            app.ModulationGUILabel.Text = 'Modulation GUI';
            app.CarrierAmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.CarrierAmplitudeEditFieldLabel.Text = 'Carrier Amplitude';
            app.CarrierAmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarrierAmplitudeEditField.Limits = [0 Inf];
            app.CarrierAmplitudeEditField.Value = 1;
            app.MessageAmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.MessageAmplitudeEditFieldLabel.Text = 'Message Amplitude';
            app.MessageAmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MessageAmplitudeEditField.Limits = [0 Inf];
            app.MessageAmplitudeEditField.Value = 1;
            app.UpdatePlotButton = uibutton(app.UIFigure, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.SNRPlotModulationDropDownLabel = uilabel(app.UIFigure);
            app.SNRPlotModulationDropDownLabel.Text = 'SNR Plot Modulation';
            app.SNRPlotModulationDropDown = uidropdown(app.UIFigure);
            app.SNRPlotModulationDropDown.Items = {'DSB', 'VSB', 'SSB', 'AM', 'PM', 'FM'};
            app.SNRPlotModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @SNRPlotModulationDropDownValueChanged, true);
            app.SNRPlotModulationDropDown.Value = 'DSB';
            app.FMKfEditFieldLabel = uilabel(app.UIFigure);
            app.FMKfEditFieldLabel.Text = 'FM Kf';
            app.FMKfEditField = uieditfield(app.UIFigure, 'numeric');
            app.FMKfEditField.Limits = [0 Inf];
            app.FMKfEditField.Value = 10;
            app.PMKpEditFieldLabel = uilabel(app.UIFigure);
            app.PMKpEditFieldLabel.Text = 'PM Kp';
            app.PMKpEditField = uieditfield(app.UIFigure, 'numeric');
            app.PMKpEditField.Limits = [0 Inf];
            app.PMKpEditField.Value = 1;
            app.AMMuEditFieldLabel = uilabel(app.UIFigure);
            app.AMMuEditFieldLabel.Text = 'AM Mu';
            app.AMMuEditField = uieditfield(app.UIFigure, 'numeric');
            app.AMMuEditField.Limits = [0 Inf];
            app.AMMuEditField.Value = 0.5;
            app.CarrierPropertiesLabel = uilabel(app.UIFigure);
            app.CarrierPropertiesLabel.Text = 'Carrier Properties';
            app.CarrierPropertiesLabel_2 = uilabel(app.UIFigure);
            app.CarrierPropertiesLabel_2.Text = 'Carrier Properties';
            app.ModulationPropertiesLabel = uilabel(app.UIFigure);
            app.ModulationPropertiesLabel.Text = 'Modulation Properties';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Modulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end